classdef ObjD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        MATLABTab                       matlab.ui.container.Tab
        LoadTestDataPanel               matlab.ui.container.Panel
        TableDataButton_2               matlab.ui.control.Button
        LoadTrainDataPanel              matlab.ui.container.Panel
        TableDataButton                 matlab.ui.control.Button
        JsonTab                         matlab.ui.container.Tab
        LoadTestDataPanel_2             matlab.ui.container.Panel
        JsonFileButton_2                matlab.ui.control.Button
        LoadTrainDataPanel_2            matlab.ui.container.Panel
        JsonFileButton                  matlab.ui.control.Button
        XMLTab                          matlab.ui.container.Tab
        LoadTestDataPanel_3             matlab.ui.container.Panel
        ImageFileButton_2               matlab.ui.control.Button
        XMLFileButton_2                 matlab.ui.control.Button
        LoadTrainDataPanel_3            matlab.ui.container.Panel
        ImageFileButton                 matlab.ui.control.Button
        XMLFileButton                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        YOLOv4Tab                       matlab.ui.container.Tab
        PreTrainNetworknumclassPanel_2  matlab.ui.container.Panel
        DropDown_4                      matlab.ui.control.DropDown
        ClassEditField_5                matlab.ui.control.NumericEditField
        ClassEditField_5Label           matlab.ui.control.Label
        SplitDataAgumentDataPanel_5     matlab.ui.container.Panel
        AgumentDataCheckBox_5           matlab.ui.control.CheckBox
        TrainEditField_5                matlab.ui.control.NumericEditField
        TrainEditField_5Label           matlab.ui.control.Label
        OptionTrainPanel_7              matlab.ui.container.Panel
        TrainLightLamp                  matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        YOLOv4TestButton                matlab.ui.control.Button
        YOLOv4TrainButton               matlab.ui.control.Button
        OptionButton_7                  matlab.ui.control.Button
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        YOLOv3Tab                       matlab.ui.container.Tab
        PreTrainNetworknumclassPanel    matlab.ui.container.Panel
        DropDown_3                      matlab.ui.control.DropDown
        ClassEditField_4                matlab.ui.control.NumericEditField
        ClassEditField_4Label           matlab.ui.control.Label
        SplitDataAgumentDataPanel_4     matlab.ui.container.Panel
        AgumentDataCheckBox_4           matlab.ui.control.CheckBox
        TrainEditField_4                matlab.ui.control.NumericEditField
        TrainEditField_4Label           matlab.ui.control.Label
        OptionTrainPanel_6              matlab.ui.container.Panel
        TrainLightLamp_5                matlab.ui.control.Lamp
        TrainLightLamp_5Label           matlab.ui.control.Label
        YOLOv3TestButton                matlab.ui.control.Button
        YOLOv3TrainButton               matlab.ui.control.Button
        OptionButton_6                  matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        YOLOv2Tab                       matlab.ui.container.Tab
        InputSizenumclassPanel_3        matlab.ui.container.Panel
        AnchorBoxEditField              matlab.ui.control.NumericEditField
        AnchorBoxEditFieldLabel         matlab.ui.control.Label
        ClassEditField_3                matlab.ui.control.NumericEditField
        ClassEditField_3Label           matlab.ui.control.Label
        WeightEditField_6               matlab.ui.control.NumericEditField
        WeightEditField_6Label          matlab.ui.control.Label
        HightEditField_6                matlab.ui.control.NumericEditField
        HightEditField_6Label           matlab.ui.control.Label
        SplitDataAgumentDataPanel_3     matlab.ui.container.Panel
        AgumentDataCheckBox_3           matlab.ui.control.CheckBox
        TrainEditField_3                matlab.ui.control.NumericEditField
        TrainEditField_3Label           matlab.ui.control.Label
        OptionTrainPanel_5              matlab.ui.container.Panel
        TrainLightLamp_4                matlab.ui.control.Lamp
        TrainLightLamp_4Label           matlab.ui.control.Label
        YOLOv2TestButton                matlab.ui.control.Button
        YOLOv2TrainButton               matlab.ui.control.Button
        OptionButton_5                  matlab.ui.control.Button
        BaseNetworkButtonGroup_3        matlab.ui.container.ButtonGroup
        DropDown_2                      matlab.ui.control.DropDown
        SSDTab                          matlab.ui.container.Tab
        InputSizenumclassPanel          matlab.ui.container.Panel
        ClassEditField                  matlab.ui.control.NumericEditField
        ClassEditFieldLabel             matlab.ui.control.Label
        WeightEditField_4               matlab.ui.control.NumericEditField
        WeightEditField_4Label          matlab.ui.control.Label
        HightEditField_4                matlab.ui.control.NumericEditField
        HightEditField_4Label           matlab.ui.control.Label
        SplitDataAgumentDataPanel       matlab.ui.container.Panel
        AgumentDataCheckBox             matlab.ui.control.CheckBox
        TrainEditField                  matlab.ui.control.NumericEditField
        TrainEditFieldLabel             matlab.ui.control.Label
        OptionTrainPanel_2              matlab.ui.container.Panel
        TrainLightLamp_3                matlab.ui.control.Lamp
        TrainLightLamp_3Label           matlab.ui.control.Label
        TestButton                      matlab.ui.control.Button
        SSDTrainButton                  matlab.ui.control.Button
        OptionButton                    matlab.ui.control.Button
        BaseNetworkButtonGroup          matlab.ui.container.ButtonGroup
        Resnet101Button                 matlab.ui.control.RadioButton
        Resnet50Button                  matlab.ui.control.RadioButton
        VGG16Button                     matlab.ui.control.RadioButton
        FasterRCNNTab                   matlab.ui.container.Tab
        InputSizenumclassPanel_2        matlab.ui.container.Panel
        ClassEditField_2                matlab.ui.control.NumericEditField
        ClassEditField_2Label           matlab.ui.control.Label
        WeightEditField_5               matlab.ui.control.NumericEditField
        WeightEditField_5Label          matlab.ui.control.Label
        HightEditField_5                matlab.ui.control.NumericEditField
        HightEditField_5Label           matlab.ui.control.Label
        SplitDataAgumentDataPanel_2     matlab.ui.container.Panel
        AgumentDataCheckBox_2           matlab.ui.control.CheckBox
        TrainEditField_2                matlab.ui.control.NumericEditField
        TrainEditField_2Label           matlab.ui.control.Label
        OptionTrainPanel_4              matlab.ui.container.Panel
        TrainLightLamp_2                matlab.ui.control.Lamp
        TrainLightLamp_2Label           matlab.ui.control.Label
        FasterRCNNTestButton            matlab.ui.control.Button
        FasterRCNNTrainButton           matlab.ui.control.Button
        OptionButton_4                  matlab.ui.control.Button
        BaseNetworkButtonGroup_2        matlab.ui.container.ButtonGroup
        DropDown                        matlab.ui.control.DropDown
        Panel                           matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % Json Read
        % 讀取訓練與測試檔案路徑
        TrainData_path
        TrainFile_path

        TestData_path
        TestFile_path
        % 建立gTruth Labeler
        TraingTruth_labeler
        TestgTruth_labeler
        % 建立完整資料集
        TrainImg
        TestImg

        % XML Read
        TrainXML_path
        TestXML_path

        % MATLAB Table Read
        answer1
        answer2

        %20220816--------------------------------------------------------
        % Label
        T_gTruth

        % 訓練測試資料
        trainingDataTbl
        testDataTbl

        % Datastore
        imdsTrain
        imdsTest
        bldsTrain
        bldsTest

        % net
        net
        featureLayer

        % YOLOv3,YOLOv4
        yoloName
        yoloNetworkInputSize
        yoloNumAnchors

        trainingData
        testData

        %20220829---------------------
        % ADD APP
        DialogApp
    end

    properties (Access = public)
        %Training Options------------------------------------------------

        %Solvername    1=sdgm
        op_Solvername = 1;

        %Mini_batch Options
        op_Max_Epochs = 30;
        op_Mini_Batch_Size = 16;
        op_Shuffle = 3;

        %Validation
        op_ValidationFrequency = 50;
        op_ValidationPatience = Inf;

        %SolverOption
        op_InitialLearnRate = 0.001;
        op_LearnRateSchedule = 1;
        op_LearnRateDropPeriod = 10;
        op_LearnRateDropFactor = 0.1;
        op_L2_Regularization = 0.0001;
        op_Momentum = 0.9;
        op_GradientDecayFactor = 0.9;
        op_SquaredGradientDecayFactor = 0.999;
        op_Epsilon = 1e-8;
        op_ResetInputNormalization = 1;
        op_BatchNormalizationStatistics = 1;

        %CheckPoint
        op_CheckpointFrequencyLabel = 1;
        op_CheckpointFrequencyUnitLabel = 1;
        op_CheckpointPathEditField = 'tempdir';

        %Gradient Cliping
        op_Gradient_Threshold = Inf;
        op_Gradient_Threshold_Method = 1;

        %Hardware Option
        op_Execution_Environment = 2;


    end

    methods (Access = public)

        function B = augmentData(app,A)
            B = cell(size(A));

            I = A{1};
            sz = size(I);
            if numel(sz)==3 && sz(3) == 3
                I = jitterColorHSV(I,...
                    'Contrast',0.2,...
                    'Hue',0,...
                    'Saturation',0.1,...
                    'Brightness',0.2);
            end

            % Randomly flip and scale image.
            tform = randomAffine2d('XReflection',true,'Scale',[1 1.1]);
            rout = affineOutputView(sz,tform,'BoundsStyle','CenterOutput');
            B{1} = imwarp(I,tform,'OutputView',rout);

            % Sanitize box data, if needed.
            A{2} = app.helperSanitizeBoxes(A{2}, sz);

            % Apply same transform to boxes.
            [B{2},indices] = bboxwarp(A{2},tform,rout,'OverlapThreshold',0.25);
            B{3} = A{3}(indices);

            % Return original data only when all boxes are removed by warping.
            if isempty(indices)
                B = A;
            end
        end

        function data = SSDpreprocessData(app,data,targetSize)
            % Resize image and bounding boxes to the targetSize.
            sz = size(data{1},[1 2]);
            scale = targetSize(1:2)./sz;
            data{1} = imresize(data{1},targetSize(1:2));

            % Sanitize box data, if needed.
            data{2} = app.helperSanitizeBoxes(data{2},sz);

            % Resize boxes to new image size.
            data{2} = bboxresize(data{2},scale);
        end

        function boxes = helperSanitizeBoxes(app,boxes, ~)
            persistent hasInvalidBoxes
            valid = all(boxes > 0, 2);
            if any(valid)
                if ~all(valid) && isempty(hasInvalidBoxes)
                    % Issue one-time warning about removing invalid boxes.
                    hasInvalidBoxes = true;
                    warning('Removing ground truth bouding box data with values <= 0.')
                end
                boxes = boxes(valid,:);
            end

        end
        function boxes = roundFractionalBoxes(app,boxes, imageSize)
            % If fractional data is present, issue one-time warning and round data and
            % clip to image size.
            persistent hasIssuedWarning

            allPixelCoordinates = isequal(floor(boxes), boxes);
            if ~allPixelCoordinates

                if isempty(hasIssuedWarning)
                    hasIssuedWarning = true;
                    warning('Rounding ground truth bounding box data to integer values.')
                end

                boxes = round(boxes);
                boxes(:,1:2) = max(boxes(:,1:2), 1);
                boxes(:,3:4) = min(boxes(:,3:4), imageSize([2 1]));
            end
        end

        function data = preprocessData(app,data, targetSize)
            % Resize the images and scale the pixels to between 0 and 1. Also scale the
            % corresponding bounding boxes.

            for ii = 1:size(data,1)
                I = data{ii,1};
                imgSize = size(I);

                % Convert an input image with single channel to 3 channels.
                if numel(imgSize) < 3
                    I = repmat(I,1,1,3);
                end
                bboxes = data{ii,2};

                I = im2single(imresize(I,targetSize(1:2)));
                scale = targetSize(1:2)./imgSize(1:2);
                bboxes = bboxresize(bboxes,scale);

                data(ii, 1:2) = {I, bboxes};
            end
        end

        function [XTrain, YTrain] = createBatchData(app,data, groundTruthBoxes, groundTruthClasses, classNames)
            % Returns images combined along the batch dimension in XTrain and
            % normalized bounding boxes concatenated with classIDs in YTrain

            % Concatenate images along the batch dimension.
            XTrain = cat(4, data{:,1});

            % Get class IDs from the class names.
            classNames = repmat({categorical(classNames')}, size(groundTruthClasses));
            [~, classIndices] = cellfun(@(a,b)ismember(a,b), groundTruthClasses, classNames, 'UniformOutput', false);

            % Append the label indexes and training image size to scaled bounding boxes
            % and create a single cell array of responses.
            combinedResponses = cellfun(@(bbox, classid)[bbox, classid], groundTruthBoxes, classIndices, 'UniformOutput', false);
            len = max( cellfun(@(x)size(x,1), combinedResponses ) );
            paddedBBoxes = cellfun( @(v) padarray(v,[len-size(v,1),0],0,'post'), combinedResponses, 'UniformOutput',false);
            YTrain = cat(4, paddedBBoxes{:,1});
        end


        %yolov3

        function [gradients, state, info] = modelGradients(app,detector, XTrain, YTrain, penaltyThreshold)
            inputImageSize = size(XTrain,1:2);

            % Gather the ground truths in the CPU for post processing
            YTrain = gather(extractdata(YTrain));

            % Extract the predictions from the detector.
            [gatheredPredictions, YPredCell, state] = forward(detector, XTrain);

            % Generate target for predictions from the ground truth data.
            [boxTarget, objectnessTarget, classTarget, objectMaskTarget, boxErrorScale] = generateTargets(gatheredPredictions,...
                YTrain, inputImageSize, detector.AnchorBoxes, penaltyThreshold);

            % Compute the loss.
            boxLoss = app.bboxOffsetLoss(YPredCell(:,[2 3 7 8]),boxTarget,objectMaskTarget,boxErrorScale);
            objLoss = app.objectnessLoss(YPredCell(:,1),objectnessTarget,objectMaskTarget);
            clsLoss = app.classConfidenceLoss(YPredCell(:,6),classTarget,objectMaskTarget);
            totalLoss = boxLoss + objLoss + clsLoss;

            info.boxLoss = boxLoss;
            info.objLoss = objLoss;
            info.clsLoss = clsLoss;
            info.totalLoss = totalLoss;

            % Compute gradients of learnables with regard to loss.
            gradients = dlgradient(totalLoss, detector.Learnables);
        end

        function boxLoss = bboxOffsetLoss(app,boxPredCell, boxDeltaTarget, boxMaskTarget, boxErrorScaleTarget)
            % Mean squared error for bounding box position.
            lossX = sum(cellfun(@(a,b,c,d) mse(a.*c.*d,b.*c.*d),boxPredCell(:,1),boxDeltaTarget(:,1),boxMaskTarget(:,1),boxErrorScaleTarget));
            lossY = sum(cellfun(@(a,b,c,d) mse(a.*c.*d,b.*c.*d),boxPredCell(:,2),boxDeltaTarget(:,2),boxMaskTarget(:,1),boxErrorScaleTarget));
            lossW = sum(cellfun(@(a,b,c,d) mse(a.*c.*d,b.*c.*d),boxPredCell(:,3),boxDeltaTarget(:,3),boxMaskTarget(:,1),boxErrorScaleTarget));
            lossH = sum(cellfun(@(a,b,c,d) mse(a.*c.*d,b.*c.*d),boxPredCell(:,4),boxDeltaTarget(:,4),boxMaskTarget(:,1),boxErrorScaleTarget));
            boxLoss = lossX+lossY+lossW+lossH;
        end

        function objLoss = objectnessLoss(app,objectnessPredCell, objectnessDeltaTarget, boxMaskTarget)
            % Binary cross-entropy loss for objectness score.
            objLoss = sum(cellfun(@(a,b,c) crossentropy(a.*c,b.*c,'TargetCategories','independent'),objectnessPredCell,objectnessDeltaTarget,boxMaskTarget(:,2)));
        end

        function clsLoss = classConfidenceLoss(app,classPredCell, classTarget, boxMaskTarget)
            % Binary cross-entropy loss for class confidence score.
            clsLoss = sum(cellfun(@(a,b,c) crossentropy(a.*c,b.*c,'TargetCategories','independent'),classPredCell,classTarget,boxMaskTarget(:,3)));
        end

        % Learning Rate Schedule Function

        function currentLR = piecewiseLearningRateWithWarmup(app,iteration, epoch, learningRate, warmupPeriod, numEpochs)
            % The piecewiseLearningRateWithWarmup function computes the current
            % learning rate based on the iteration number.
            persistent warmUpEpoch;

            if iteration <= warmupPeriod
                % Increase the learning rate for number of iterations in warmup period.
                currentLR = learningRate * ((iteration/warmupPeriod)^4);
                warmUpEpoch = epoch;
            elseif iteration >= warmupPeriod && epoch < warmUpEpoch+floor(0.6*(numEpochs-warmUpEpoch))
                % After warm up period, keep the learning rate constant if the remaining number of epochs is less than 60 percent.
                currentLR = learningRate;

            elseif epoch >= warmUpEpoch + floor(0.6*(numEpochs-warmUpEpoch)) && epoch < warmUpEpoch+floor(0.9*(numEpochs-warmUpEpoch))
                % If the remaining number of epochs is more than 60 percent but less
                % than 90 percent multiply the learning rate by 0.1.
                currentLR = learningRate*0.1;

            else
                % If remaining epochs are more than 90 percent multiply the learning
                % rate by 0.01.
                currentLR = learningRate*0.01;
            end

        end

        % Utility Functions

        function [lossPlotter, learningRatePlotter] = configureTrainingProgressPlotter(app,f)
            % Create the subplots to display the loss and learning rate.
            figure(f);
            clf
            subplot(2,1,1);
            ylabel('Learning Rate');
            xlabel('Iteration');
            learningRatePlotter = animatedline;
            subplot(2,1,2);
            ylabel('Total Loss');
            xlabel('Iteration');
            lossPlotter = animatedline;
        end

        function displayLossInfo(app,epoch, iteration, currentLR, lossInfo)
            % Display loss information for each iteration.
            disp("Epoch : " + epoch + " | Iteration : " + iteration + " | Learning Rate : " + currentLR + ...
                " | Total Loss : " + double(gather(extractdata(lossInfo.totalLoss))) + ...
                " | Box Loss : " + double(gather(extractdata(lossInfo.boxLoss))) + ...
                " | Object Loss : " + double(gather(extractdata(lossInfo.objLoss))) + ...
                " | Class Loss : " + double(gather(extractdata(lossInfo.clsLoss))));
        end

        function updatePlots(app,lossPlotter, learningRatePlotter, iteration, currentLR, totalLoss)
            % Update loss and learning rate plots.
            addpoints(lossPlotter, iteration, double(extractdata(gather(totalLoss))));
            addpoints(learningRatePlotter, iteration, currentLR);
            drawnow
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: JsonFileButton
        function JsonFileButtonPushed(app, event)
            % Json
            % 讀檔
            [app.TrainFile_path,app.TrainData_path] = uigetfile('*.json');
            % 建立字串
            filename = [app.TrainData_path,app.TrainFile_path];
            % 解碼整理
            strData = fileread(filename);
            DecodeData = jsondecode(strData);

            idx = findgroups([DecodeData.annotations.image_id]);
            T_bbox = splitapply(@(x){x},[DecodeData.annotations.bbox],idx)';
            T_file = {DecodeData.images.file_name}';
            % arrayfun(@(x)getfield(x,'file_name'),DecodeData.images,'UniformOutput',false);
            app.TraingTruth_labeler = table(T_file,T_bbox);

            % 建立字串
            app.TrainImg = strcat(app.TrainData_path,string(app.TraingTruth_labeler.T_file));

            % 建Image DataStore
            % Image
            app.imdsTrain = imageDatastore(app.TrainImg);
            % Bbox
            app.bldsTrain = boxLabelDatastore(app.TraingTruth_labeler(:, 2:end));

            % 合併DataStore
            app.trainingData = combine(app.imdsTrain, app.bldsTrain);
        end

        % Button pushed function: JsonFileButton_2
        function JsonFileButton_2Pushed(app, event)
            % Json
            % 讀檔
            [app.TestFile_path,app.TestData_path] = uigetfile('*.json');
            % 建立字串
            filename2 = [app.TestData_path,app.TestFile_path];
            % 解碼整理
            strData2 = fileread(filename2);
            DecodeData2 = jsondecode(strData2);
            %
            idx2 = findgroups([DecodeData2.annotations.image_id]);
            T_bbox2 = splitapply(@(x){x},[DecodeData2.annotations.bbox],idx2)';
            T_file2 = {DecodeData2.images.file_name}';
            % arrayfun(@(x)getfield(x,'file_name'),DecodeData.images,'UniformOutput',false);
            app.TestgTruth_labeler = table(T_file2,T_bbox2);

            % 建立字串
            app.TestImg = strcat(app.TestData_path,string(app.TestgTruth_labeler.T_file2));

            % 建Image DataStore
            % Image
            app.imdsTest = imageDatastore(app.TestImg);
            % bbox
            app.bldsTest = boxLabelDatastore(app.TestgTruth_labeler(:, 2:end));

            % 合併DataStore
            app.testData = combine(app.imdsTest, app.bldsTest);
        end

        % Button pushed function: XMLFileButton
        function XMLFileButtonPushed(app, event)
            % XML 訓練
            % 載入XML路徑資料夾
            app.TrainXML_path = uigetdir();
            ds = fileDatastore(app.TrainXML_path,'ReadFcn',@readFcn);
            T_bbox= readall(ds);
            ds2 = fileDatastore(app.TrainXML_path,'ReadFcn',@readFcn2);
            T_file= readall(ds2);

            app.TraingTruth_labeler = table(T_file,T_bbox);

        end

        % Button pushed function: ImageFileButton
        function ImageFileButtonPushed(app, event)
            % XML 訓練
            % 載入Image路徑
            app.TrainData_path = uigetdir();
            % 做整併
            app.TrainData_path = [app.TrainData_path,'\'];
            app.TrainImg = strcat(app.TrainData_path,string(app.TraingTruth_labeler.T_file));

            % 建Image DataStore
            % Image
            app.imdsTrain = imageDatastore(app.TrainImg);
            % Bbox
            app.bldsTrain = boxLabelDatastore(app.TraingTruth_labeler(:, 2:end));

            % 合併DataStore
            app.trainingData = combine(app.imdsTrain, app.bldsTrain);

        end

        % Button pushed function: XMLFileButton_2
        function XMLFileButton_2Pushed(app, event)
            % XML 測試
            % 載入XML路徑資料夾
            app.TestXML_path = uigetdir();
            ds = fileDatastore(app.TestXML_path,'ReadFcn',@readFcn);
            T_bbox2= readall(ds);
            ds2 = fileDatastore(app.TestXML_path,'ReadFcn',@readFcn2);
            T_file2= readall(ds2);

            app.TestgTruth_labeler = table(T_file2,T_bbox2);
        end

        % Button pushed function: ImageFileButton_2
        function ImageFileButton_2Pushed(app, event)
            % XML 測試
            % 載入Image路徑
            app.TestData_path = uigetdir();
            % 做整併
            app.TestData_path = [app.TestData_path,'\'];
            app.TestImg = strcat(app.TestData_path,string(app.TestgTruth_labeler.T_file));

            % 建Image DataStore
            % Image
            app.imdsTest = imageDatastore(app.TestImg);
            % bbox
            app.bldsTest = boxLabelDatastore(app.TestgTruth_labeler(:, 2:end));

            % 合併DataStore
            app.testData = combine(app.imdsTest, app.bldsTest);

        end

        % Button pushed function: TableDataButton
        function TableDataButtonPushed(app, event)
            % Table格式 訓練資料
            app.answer1 = questdlg(sprintf("1 : 選擇標記檔案(File Table格式) 檔名需為T_gTruth \n2 : 選擇標記變數(workspace)"),'Label Source','1','2','1');

            if strcmp(app.answer1,'1')
                [app.TrainFile_path,app.TrainData_path] = uigetfile('*.mat');
                try
                    % 取檔案
                    filename = [app.TrainData_path,app.TrainFile_path];
                    load(filename)
                    app.T_gTruth = T_gTruth;
                catch
                    errordlg('No File is selected');
                end
                message = {'成功載入'};
                uialert(app.UIFigure,message,'Success','Icon','success');

            elseif strcmp(app.answer1,'2')
                app.answer2 = inputdlg(sprintf("Enter the name of variable in workspace to represent your label source:\nThe variable must be Tabel"),'Workspace Variable for Label Source', [1 90]);
                try
                    T_gTruth = evalin('base',string(app.answer2));
                    % 資料分割(DataSet Split)
                    rng(0)
                    shuffledIndices = randperm(height(T_gTruth));
                    idx = floor(0.8 * height(T_gTruth));

                    trainingIdx = 1:idx;
                    app.trainingDataTbl = T_gTruth(shuffledIndices(trainingIdx),:);

                    testIdx = trainingIdx(end)+1 : length(shuffledIndices);
                    app.testDataTbl = T_gTruth(shuffledIndices(testIdx),:);

                    % 建立資料庫
                    % Train
                    app.imdsTrain = imageDatastore(app.trainingDataTbl.imageFilename);
                    app.bldsTrain = boxLabelDatastore(app.trainingDataTbl(:,2:end));

                    % Test
                    app.imdsTest = imageDatastore(app.testDataTbl.imageFilename);
                    app.bldsTest = boxLabelDatastore(app.testDataTbl(:,2:end));

                    % 整併資料庫
                    app.trainingData = combine(app.imdsTrain,app.bldsTrain);
                    app.testData = combine(app.imdsTest,app.bldsTest);

                catch ME
                    errordlg(ME.message,ME.identifier);
                end
                message = {'成功載入'};
                uialert(app.UIFigure,message,'Success','Icon','success');
            end


        end

        % Button pushed function: TableDataButton_2
        function TableDataButton_2Pushed(app, event)
            % Table格式 測試資料
            app.answer1 = questdlg(sprintf("1 : 選擇標記檔案(File Table格式) 檔名需為gTruth \n2 : 選擇標記變數 Table格式(workspace)"),'Label Source','1','2','1');

            if strcmp(app.answer1,'1')
                [app.TestFile_path,app.TestData_path] = uigetfile('*.mat');
                try
                    %取檔案
                    filename = [app.TestData_path,app.TestFile_path];
                    load(filename)
                    % Image
                    app.imdsTest = imageDatastore(gTruth.imageFilename);
                    % Bbox
                    app.bldsTest = boxLabelDatastore(gTruth(:,2:end));

                    % 合併DataStore
                    app.testData = combine(app.imdsTest, app.bldsTest);

                catch
                    errordlg('No File is selected');
                end
                message = {'成功載入'};
                uialert(app.UIFigure,message,'Success','Icon','success');

            elseif strcmp(app.answer1,'2')
                app.answer2 = inputdlg(sprintf("Enter the name of variable in workspace to represent your label source:\nThe variable must be Tabel"),'Workspace Variable for Label Source', [1 90]);
                try
                    gTruth = evalin('base',string(app.answer2));
                    % Image
                    app.imdsTrain = imageDatastore(gTruth.imageFilename);
                    % Bbox
                    app.bldsTest = boxLabelDatastore(gTruth(:,2:end));

                    % 合併DataStore
                    app.testData = combine(app.imdsTrain, app.bldsTest);

                catch ME
                    errordlg(ME.message,ME.identifier);
                end
                message = {'成功載入'};
                uialert(app.UIFigure,message,'Success','Icon','success');
            end
        end

        % Callback function
        function GroundTruthDataButtonPushed(app, event)

        end

        % Callback function
        function GroundTruthDataButton_2Pushed(app, event)

        end

        % Value changed function: TrainEditField
        function TrainEditFieldValueChanged(app, event)
            Trainvalue = app.TrainEditField.Value;
            Testvalue  = 100 - Trainvalue;
        end

        % Button pushed function: SSDTrainButton
        function SSDTrainButtonPushed(app, event)
            Trainspilt = app.TrainEditField.Value/100;
            % 資料分割(DataSet Split)
            rng(0)
            shuffledIndices = randperm(height(app.T_gTruth));
            idx = floor(Trainspilt* height(app.T_gTruth));

            trainingIdx = 1:idx;
            app.trainingDataTbl = app.T_gTruth(shuffledIndices(trainingIdx),:);

            testIdx = trainingIdx(end)+1 : length(shuffledIndices);
            app.testDataTbl = app.T_gTruth(shuffledIndices(testIdx),:);

            % 建立資料庫
            % Train
            app.imdsTrain = imageDatastore(app.trainingDataTbl.imageFilename);
            app.bldsTrain = boxLabelDatastore(app.trainingDataTbl(:,2:end));

            % Test
            app.imdsTest = imageDatastore(app.testDataTbl.imageFilename);
            app.bldsTest = boxLabelDatastore(app.testDataTbl(:,2:end));

            % 整併資料庫
            app.trainingData = combine(app.imdsTrain,app.bldsTrain);
            app.testData = combine(app.imdsTest,app.bldsTest);

            % 資料增量
            augmentedTrainingData = transform(app.trainingData,@app.augmentData);

            augmentedData = cell(4,1);
            for k = 1:4
                data = read(augmentedTrainingData);
                augmentedData{k} = insertShape(data{1},'Rectangle',data{2});
                reset(augmentedTrainingData);
            end
            montage(augmentedData,'parent',app.UIAxes)

            % 建置網路架構 SSD + mobilenetv2
            inputsizeH = app.HightEditField_4.Value;
            inputsizeW = app.WeightEditField_4.Value;
            inputSize = [inputsizeH inputsizeW 3];
            numClasses = app.ClassEditField.Value;

            % 選擇網路架構
            if (app.Resnet101Button.Value == 1)
                net = 'resnet101';
            elseif(app.Resnet50Button.Value == 1)
                net = 'resnet50';
            elseif(app.VGG16Button.Value == 1)
                net = 'vgg16';
            else

            end
            lgraph = ssdLayers(inputSize, numClasses, net);

            % 流程前處理 資料Size校正
            if (app.AgumentDataCheckBox.Value ==1) %check agument

                preprocessedTrainingData = transform(augmentedTrainingData,@(data)app.SSDpreprocessData(data,inputSize));

                %                 data = read(preprocessedTrainingData);
                %
                %                 I = data{1};
                %                 bbox = data{2};
                %                 annotatedImage = insertShape(I,'Rectangle',bbox);
                %                 annotatedImage = imresize(annotatedImage,2);
                %figure,imshow(annotatedImage)

            else
                preprocessedTrainingData = transform(app.trainingData,@(data)app.SSDpreprocessData(data,inputSize));

                %                 data = read(preprocessedTrainingData);
                %
                %                 I = data{1};
                %                 bbox = data{2};
                %                 annotatedImage = insertShape(I,'Rectangle',bbox);
                %                 annotatedImage = imresize(annotatedImage,2);
            end

            % 資料參數
            options = trainingOptions('sgdm', ...
                'MiniBatchSize', 128, ....
                'InitialLearnRate',0.001, ...
                'LearnRateSchedule', 'piecewise', ...
                'LearnRateDropPeriod', 30, ...
                'LearnRateDropFactor', 0.8, ...
                'MaxEpochs', 20, ...
                'VerboseFrequency', 50, ...
                'CheckpointPath', tempdir, ...
                'Shuffle','every-epoch');

            % Train
            app.TrainLightLamp_3.Color = [1.00,0.00,0.00];
            drawnow;
            SSDdetector = trainSSDObjectDetector(preprocessedTrainingData,lgraph,options);
            app.TrainLightLamp_3.Color = [0.00,1.00,0.00];
            drawnow;

            % save
            save('SSDDetector.mat','SSDdetector')
        end

        % Button pushed function: FasterRCNNTrainButton
        function FasterRCNNTrainButtonPushed(app, event)
            Trainspilt = app.TrainEditField_2.Value/100; %**
            % 資料分割(DataSet Split)
            rng(0)
            shuffledIndices = randperm(height(app.T_gTruth));
            idx = floor(Trainspilt* height(app.T_gTruth));

            trainingIdx = 1:idx;
            app.trainingDataTbl = app.T_gTruth(shuffledIndices(trainingIdx),:);

            testIdx = trainingIdx(end)+1 : length(shuffledIndices);
            app.testDataTbl = app.T_gTruth(shuffledIndices(testIdx),:);

            % 建立資料庫
            % Train
            app.imdsTrain = imageDatastore(app.trainingDataTbl.imageFilename);
            app.bldsTrain = boxLabelDatastore(app.trainingDataTbl(:,2:end));

            % Test
            app.imdsTest = imageDatastore(app.testDataTbl.imageFilename);
            app.bldsTest = boxLabelDatastore(app.testDataTbl(:,2:end));

            % 整併資料庫
            app.trainingData = combine(app.imdsTrain,app.bldsTrain);
            app.testData = combine(app.imdsTest,app.bldsTest);

            % 資料增量
            augmentedTrainingData = transform(app.trainingData,@app.augmentData);

            augmentedData = cell(4,1);
            for k = 1:4
                data = read(augmentedTrainingData);
                augmentedData{k} = insertShape(data{1},'Rectangle',data{2});
                reset(augmentedTrainingData);
            end
            montage(augmentedData,'parent',app.UIAxes)

            % 建置網路架構 SSD + mobilenetv2
            inputsizeH = app.HightEditField_5.Value;
            inputsizeW = app.WeightEditField_5.Value;
            inputSize = [inputsizeH inputsizeW 3];
            numClasses = app.ClassEditField_2.Value;

            % 選擇網路架構
            switch(str2num(app.DropDown.Value))
                case 1
                    app.net = 'alexnet';
                    app.featureLayer = 'relu5';
                case 2
                    app.net = 'vgg16';
                    app.featureLayer = 'relu5';
                case 3
                    app.net = 'vgg19';
                    app.featureLayer = 'relu5';
                case 4
                    app.net = 'resnet18';
                    app.featureLayer = 'res5b_relu';
                case 5
                    app.net = 'resnet50';
                    app.featureLayer = 'activation_49_relu';
                case 6
                    app.net = 'resnet101';
                    app.featureLayer = 'res5c_relu';
                case 7
                    app.net = 'inceptionv3';
                    app.featureLayer = 'activation_94_relu';
                case 8
                    app.net = 'googlenet';
                    app.featureLayer = 'inception_5b-relu_pool_proj';
                case 9
                    app.net = 'inceptionresnetv2';
                    app.featureLayer = 'conv_7b_ac';
                case 10
                    app.net = 'squeezenet';
                    app.featureLayer = 'out_relu';
                case 11
                    app.net = 'mobilenetv2';
                    app.featureLayer ='out_relu';
            end

            anchorBoxes = [64,64; 128,128; 192,192];

            lgraph = fasterRCNNLayers(inputSize ,numClasses,anchorBoxes, ...
                app.net,app.featureLayer);


            % 流程前處理 資料Size校正
            if (app.AgumentDataCheckBox_2.Value ==1) %check agument

                preprocessedTrainingData = transform(augmentedTrainingData,@(data)app.SSDpreprocessData(data,inputSize));

                %data = read(preprocessedTrainingData);

                %I = data{1};
                %bbox = data{2};
                %annotatedImage = insertShape(I,'Rectangle',bbox);
                %annotatedImage = imresize(annotatedImage,2);
                %imshow(annotatedImage,'parent',app.UIAxes)
                %figure,imshow(annotatedImage)

            else
                preprocessedTrainingData = transform(app.trainingData,@(data)app.SSDpreprocessData(data,inputSize));

                %data = read(preprocessedTrainingData);

                %I = data{1};
                %bbox = data{2};
                %annotatedImage = insertShape(I,'Rectangle',bbox);
                %annotatedImage = imresize(annotatedImage,2);
                %imshow(annotatedImage,'parent',app.UIAxes)
            end
            % 資料參數
            options = trainingOptions('sgdm',...
                'MaxEpochs',20,...
                'MiniBatchSize',16,...
                'InitialLearnRate',0.001,...
                'CheckpointPath',tempdir,...
                'ValidationData',app.testData);

            % 訓練
            app.TrainLightLamp_2.Color = [1.00,0.00,0.00];
            drawnow;
            [FasterRCNNdetector, info] = trainFasterRCNNObjectDetector(app.trainingData,lgraph,options, ...
                'NegativeOverlapRange',[0 0.3], ...
                'PositiveOverlapRange',[0.6 1]);
            app.TrainLightLamp_2.Color = [0.00,1.00,0.00];
            drawnow;

            % Save
            save('FasterRCNNDetector.mat','FasterRCNNdetector')
        end

        % Button pushed function: YOLOv2TrainButton
        function YOLOv2TrainButtonPushed(app, event)
            Trainspilt = app.TrainEditField_3.Value/100; %**
            % 資料分割(DataSet Split)
            rng(0)
            shuffledIndices = randperm(height(app.T_gTruth));
            idx = floor(Trainspilt* height(app.T_gTruth));

            trainingIdx = 1:idx;
            app.trainingDataTbl = app.T_gTruth(shuffledIndices(trainingIdx),:);

            testIdx = trainingIdx(end)+1 : length(shuffledIndices);
            app.testDataTbl = app.T_gTruth(shuffledIndices(testIdx),:);

            % 建立資料庫
            % Train
            app.imdsTrain = imageDatastore(app.trainingDataTbl.imageFilename);
            app.bldsTrain = boxLabelDatastore(app.trainingDataTbl(:,2:end));

            % Test
            app.imdsTest = imageDatastore(app.testDataTbl.imageFilename);
            app.bldsTest = boxLabelDatastore(app.testDataTbl(:,2:end));

            % 整併資料庫
            app.trainingData = combine(app.imdsTrain,app.bldsTrain);
            app.testData = combine(app.imdsTest,app.bldsTest);

            % 資料增量
            augmentedTrainingData = transform(app.trainingData,@app.augmentData);

            augmentedData = cell(4,1);
            for k = 1:4
                data = read(augmentedTrainingData);
                augmentedData{k} = insertShape(data{1},'Rectangle',data{2});
                reset(augmentedTrainingData);
            end
            montage(augmentedData,'parent',app.UIAxes)

            % 建置網路架構 YOLOv2
            inputsizeH = app.HightEditField_6.Value;
            inputsizeW = app.WeightEditField_6.Value;
            inputSize = [inputsizeH inputsizeW 3];
            numClasses = app.ClassEditField_3.Value;
            numAnchors = app.AnchorBoxEditField.Value;
            trainingDataForEstimation = transform(app.trainingData,@(data)app.SSDpreprocessData(data,inputSize));
            [anchorBoxes, meanIoU] = estimateAnchorBoxes(trainingDataForEstimation, numAnchors);

            switch(str2num(app.DropDown_2.Value))
                case 1
                    app.net = alexnet;
                    app.featureLayer = 'relu5';
                case 2
                    app.net = vgg16;
                    app.featureLayer = 'relu5';
                case 3
                    app.net = vgg19;
                    app.featureLayer = 'relu5';
                case 4
                    app.net = resnet18;
                    app.featureLayer = 'res5b_relu';
                case 5
                    app.net = resnet50;
                    app.featureLayer = 'activation_49_relu';
                case 6
                    app.net = resnet101';
                    app.featureLayer = 'res5c_relu';
                case 7
                    app.net = inceptionv3;
                    app.featureLayer = 'activation_94_relu';
                case 8
                    app.net = googlenet;
                    app.featureLayer = 'inception_5b-relu_pool_proj';
                case 9
                    app.net = inceptionresnetv2;
                    app.featureLayer = conv_7b_ac;
                case 10
                    app.net = squeezenet;
                    app.featureLayer = out_relout_relu';
            end

            lgraph = yolov2Layers(inputSize,numClasses,anchorBoxes,app.net,app.featureLayer);


            % 流程前處理 資料Size校正
            if (app.AgumentDataCheckBox_3.Value ==1) %check agument

                preprocessedTrainingData = transform(augmentedTrainingData,@(data)app.SSDpreprocessData(data,inputSize));

                %data = read(preprocessedTrainingData);

                %I = data{1};
                %bbox = data{2};
                %annotatedImage = insertShape(I,'Rectangle',bbox);
                %annotatedImage = imresize(annotatedImage,2);
                %figure,imshow(annotatedImage)

            else
                preprocessedTrainingData = transform(app.trainingData,@(data)app.SSDpreprocessData(data,inputSize));
                %
                %                 data = read(preprocessedTrainingData);
                %
                %                 I = data{1};
                %                 bbox = data{2};
                %                 annotatedImage = insertShape(I,'Rectangle',bbox);
                %                 annotatedImage = imresize(annotatedImage,2);
            end

            % 資料參數
            options = trainingOptions('sgdm', ...
                'MiniBatchSize', 32, ....
                'InitialLearnRate',1e-3, ...
                'LearnRateSchedule', 'piecewise', ...
                'LearnRateDropPeriod', 30, ...
                'LearnRateDropFactor', 0.8, ...
                'MaxEpochs', 100, ...
                'VerboseFrequency', 50, ...
                'CheckpointPath', tempdir, ...
                'Shuffle','every-epoch');

            % Train
            app.TrainLightLamp_4.Color = [1.00,0.00,0.00];
            drawnow;
            [Yolov2detector,info] = trainYOLOv2ObjectDetector(preprocessedTrainingData,lgraph,options);
            app.TrainLightLamp_4.Color = [0.00,1.00,0.00];
            drawnow;

            % Save
            save('Yolov2Detector.mat','Yolov2detector')

        end

        % Button pushed function: YOLOv3TrainButton
        function YOLOv3TrainButtonPushed(app, event)
            Trainspilt = app.TrainEditField_4.Value/100; %**
            % 資料分割(DataSet Split)
            rng(0)
            shuffledIndices = randperm(height(app.T_gTruth));
            idx = floor(Trainspilt* height(app.T_gTruth));

            trainingIdx = 1:idx;
            app.trainingDataTbl = app.T_gTruth(shuffledIndices(trainingIdx),:);

            testIdx = trainingIdx(end)+1 : length(shuffledIndices);
            app.testDataTbl = app.T_gTruth(shuffledIndices(testIdx),:);

            % 建立資料庫
            % Train
            app.imdsTrain = imageDatastore(app.trainingDataTbl.imageFilename);
            app.bldsTrain = boxLabelDatastore(app.trainingDataTbl(:,2:end));

            % Test
            app.imdsTest = imageDatastore(app.testDataTbl.imageFilename);
            app.bldsTest = boxLabelDatastore(app.testDataTbl(:,2:end));

            % 整併資料庫
            app.trainingData = combine(app.imdsTrain,app.bldsTrain);
            app.testData = combine(app.imdsTest,app.bldsTest);

            % 資料增量
            augmentedTrainingData = transform(app.trainingData,@app.augmentData);

            augmentedData = cell(4,1);
            for k = 1:4
                data = read(augmentedTrainingData);
                augmentedData{k} = insertShape(data{1},'Rectangle',data{2});
                reset(augmentedTrainingData);
            end
            montage(augmentedData,'parent',app.UIAxes)

            %(定義YOLOv3物件偵測演算法)

            model = str2num(app.DropDown_3.Value);

            switch model
                case 1
                    app.yoloName = 'tiny-yolov3-coco';
                    app.yoloNetworkInputSize = [416 416];
                    app.yoloNumAnchors = 6;
                case 2
                    app.yoloName = 'darknet53-coco';
                    app.yoloNetworkInputSize = [608 608];
                    app.yoloNumAnchors = 9;
            end
            classNames =  {app.T_gTruth.Properties.VariableNames{2:end}};


            % Anchors
            trainingDataForEstimation = transform(app.trainingData, @(data)app.preprocessData(data, app.yoloNetworkInputSize));
            [anchors, meanIoU] = estimateAnchorBoxes(trainingDataForEstimation, app.yoloNumAnchors);

            area = anchors(:, 1).*anchors(:, 2);
            [~, idx] = sort(area, 'descend');
            anchors = anchors(idx, :);

            if app.yoloNumAnchors == 9
                anchorBoxes = {anchors(1:3,:)
                    anchors(4:6,:)
                    anchors(7:9,:)
                    };

            elseif app.yoloNumAnchors == 6
                anchorBoxes = {anchors(1:3,:)
                    anchors(4:6,:)
                    };
            end


            % 組合網路架構
            yolov3Detector = yolov3ObjectDetector(app.yoloName,classNames,anchorBoxes);

            % Preprocess Training Data
            preprocessedTrainingData = transform(augmentedTrainingData,@(data)app.preprocessData(data,app.yoloNetworkInputSize));

            % Train Options

            numEpochs = 200;
            miniBatchSize = 16;
            learningRate = 0.01;
            warmupPeriod = 1000;
            l2Regularization = 0.0005;
            penaltyThreshold = 0.5;
            velocity = [];

            %Setting Minibatchqueue
            if canUseParallelPool
                dispatchInBackground = true;
            else
                dispatchInBackground = false;
            end

            mbqTrain = minibatchqueue(preprocessedTrainingData, 2,...
                "MiniBatchSize", miniBatchSize,...
                "MiniBatchFcn", @(images, boxes, labels)app.createBatchData(images, boxes, labels, classNames), ...
                "MiniBatchFormat", ["SSCB", ""],...
                "DispatchInBackground", dispatchInBackground,...
                "OutputEnvironment","gpu",...
                "OutputCast", ["", "double"]);

            app.TrainLightLamp_5.Color = [1.00,0.00,0.00];
            drawnow;
            % Train
            doTraining = 1;
            if doTraining

                % Create subplots for the learning rate and mini-batch loss.
                fig = figure;
                [lossPlotter, learningRatePlotter] = app.configureTrainingProgressPlotter(fig);

                iteration = 0;
                % Custom training loop.
                for epoch = 1:numEpochs

                    reset(mbqTrain);
                    shuffle(mbqTrain);

                    while(hasdata(mbqTrain))
                        iteration = iteration + 1;

                        [XTrain, YTrain] = next(mbqTrain);

                        % Evaluate the model gradients and loss using dlfeval and the
                        % modelGradients function.
                        [gradients, state, lossInfo] = dlfeval(@app.modelGradients, yolov3Detector, XTrain, YTrain, penaltyThreshold);

                        % Apply L2 regularization.
                        gradients = dlupdate(@(g,w) g + l2Regularization*w, gradients, yolov3Detector.Learnables);

                        % Determine the current learning rate value.
                        currentLR = app.piecewiseLearningRateWithWarmup(iteration, epoch, learningRate, warmupPeriod, numEpochs);

                        % Update the detector learnable parameters using the SGDM optimizer.
                        [yolov3Detector.Learnables, velocity] = sgdmupdate(yolov3Detector.Learnables, gradients, velocity, currentLR);

                        % Update the state parameters of dlnetwork.
                        yolov3Detector.State = state;

                        % Display progress.
                        displayLossInfo(epoch, iteration, currentLR, lossInfo);

                        % Update training plot with new points.
                        updatePlots(lossPlotter, learningRatePlotter, iteration, currentLR, lossInfo.totalLoss);
                    end
                end
            else
                app.TrainLightLamp_5.Color = [0.00,1.00,0.00];
                drawnow;
                yolov3Detector = preTrainedDetector;
            end

            % Save
            save('yolov3Detector.mat','yolov3Detector')

        end

        % Button pushed function: YOLOv4TrainButton
        function YOLOv4TrainButtonPushed(app, event)
            Trainspilt = app.TrainEditField_5.Value/100; %**
            % 資料分割(DataSet Split)
            rng(0)
            shuffledIndices = randperm(height(app.T_gTruth));
            idx = floor(Trainspilt* height(app.T_gTruth));

            trainingIdx = 1:idx;
            app.trainingDataTbl = app.T_gTruth(shuffledIndices(trainingIdx),:);

            testIdx = trainingIdx(end)+1 : length(shuffledIndices);
            app.testDataTbl = app.T_gTruth(shuffledIndices(testIdx),:);

            % 建立資料庫
            % Train
            app.imdsTrain = imageDatastore(app.trainingDataTbl.imageFilename);
            app.bldsTrain = boxLabelDatastore(app.trainingDataTbl(:,2:end));

            % Test
            app.imdsTest = imageDatastore(app.testDataTbl.imageFilename);
            app.bldsTest = boxLabelDatastore(app.testDataTbl(:,2:end));

            % 整併資料庫
            app.trainingData = combine(app.imdsTrain,app.bldsTrain);
            app.testData = combine(app.imdsTest,app.bldsTest);

            % 資料增量
            augmentedTrainingData = transform(app.trainingData,@app.augmentData);

            augmentedData = cell(4,1);
            for k = 1:4
                data = read(augmentedTrainingData);
                augmentedData{k} = insertShape(data{1},'Rectangle',data{2});
                reset(augmentedTrainingData);
            end
            montage(augmentedData,'parent',app.UIAxes)

            %(定義YOLOv4物件偵測演算法)

            model = str2num(app.DropDown_3.Value);

            switch model
                case 1
                    app.yoloName = 'tiny-yolov4-coco';
                    app.yoloNetworkInputSize = [416 416 3];
                    app.yoloNumAnchors = 6;
                case 2
                    app.yoloName = 'csp-darknet53-coco';
                    app.yoloNetworkInputSize = [608 608 3];
                    app.yoloNumAnchors = 9;
            end
            classNames =  {app.T_gTruth.Properties.VariableNames{2:end}};

            % Anchors
            trainingDataForEstimation = transform(app.trainingData, @(data)app.preprocessData(data, app.yoloNetworkInputSize));
            [anchors, meanIoU] = estimateAnchorBoxes(trainingDataForEstimation, app.yoloNumAnchors);

            area = anchors(:, 1).*anchors(:, 2);
            [~, idx] = sort(area, 'descend');
            anchors = anchors(idx, :);

            if app.yoloNumAnchors == 9
                anchorBoxes = {anchors(1:3,:)
                    anchors(4:6,:)
                    anchors(7:9,:)
                    };

            elseif app.yoloNumAnchors == 6
                anchorBoxes = {anchors(1:3,:)
                    anchors(4:6,:)
                    };
            end
            %
            detector = yolov4ObjectDetector(app.yoloName,classNames,anchorBoxes,InputSize=app.yoloNetworkInputSize);

            % 資料參數
            options = trainingOptions("adam",...
                GradientDecayFactor=0.9,...
                SquaredGradientDecayFactor=0.999,...
                InitialLearnRate=0.001,...
                LearnRateSchedule="none",...
                MiniBatchSize=2,...
                L2Regularization=0.0005,...
                MaxEpochs=70,...
                BatchNormalizationStatistics="moving",...
                DispatchInBackground=true,...
                ResetInputNormalization=false,...
                Shuffle="every-epoch",...
                VerboseFrequency=20,...
                CheckpointPath=tempdir);

            % Train
            app.TrainLightLamp.Color = [1.00,0.00,0.00];
            drawnow;
            [detector,info] = trainYOLOv4ObjectDetector(augmentedTrainingData,detector,options);
            app.TrainLightLamp.Color = [0.00,1.00,0.00];

            % Save
            save('trainYOLOv4ObjectorDetector.mat','detector')

        end

        % Button pushed function: OptionButton_4
        function OptionButton_4Pushed(app, event)
            app.DialogApp = OptionsList(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'v')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.YOLOv4Tab = uitab(app.TabGroup);
            app.YOLOv4Tab.Title = 'YOLOv4';
            app.ButtonGroup_2 = uibuttongroup(app.YOLOv4Tab);
            app.OptionTrainPanel_7 = uipanel(app.YOLOv4Tab);
            app.OptionTrainPanel_7.Title = 'Option & Train';
            app.OptionButton_7 = uibutton(app.OptionTrainPanel_7, 'push');
            app.OptionButton_7.Text = 'Option';
            app.YOLOv4TrainButton = uibutton(app.OptionTrainPanel_7, 'push');
            app.YOLOv4TrainButton.ButtonPushedFcn = createCallbackFcn(app, @YOLOv4TrainButtonPushed, true);
            app.YOLOv4TrainButton.Text = {'YOLOv4 '; 'Train'};
            app.YOLOv4TestButton = uibutton(app.OptionTrainPanel_7, 'push');
            app.YOLOv4TestButton.Visible = 'off';
            app.YOLOv4TestButton.Text = {'YOLOv4'; 'Test'};
            app.Label = uilabel(app.OptionTrainPanel_7);
            app.Label.Text = 'Train Light';
            app.TrainLightLamp = uilamp(app.OptionTrainPanel_7);
            app.SplitDataAgumentDataPanel_5 = uipanel(app.YOLOv4Tab);
            app.SplitDataAgumentDataPanel_5.Title = 'Split Data & Agument Data';
            app.TrainEditField_5Label = uilabel(app.SplitDataAgumentDataPanel_5);
            app.TrainEditField_5Label.Text = 'Train(%)';
            app.TrainEditField_5 = uieditfield(app.SplitDataAgumentDataPanel_5, 'numeric');
            app.TrainEditField_5.Value = 85;
            app.AgumentDataCheckBox_5 = uicheckbox(app.SplitDataAgumentDataPanel_5);
            app.AgumentDataCheckBox_5.Text = 'Agument Data';
            app.AgumentDataCheckBox_5.Value = true;
            app.PreTrainNetworknumclassPanel_2 = uipanel(app.YOLOv4Tab);
            app.PreTrainNetworknumclassPanel_2.Title = 'PreTrain Network & num class';
            app.ClassEditField_5Label = uilabel(app.PreTrainNetworknumclassPanel_2);
            app.ClassEditField_5Label.Text = 'Class';
            app.ClassEditField_5 = uieditfield(app.PreTrainNetworknumclassPanel_2, 'numeric');
            app.ClassEditField_5.Value = 1;
            app.DropDown_4 = uidropdown(app.PreTrainNetworknumclassPanel_2);
            app.DropDown_4.Items = {'Tiny-yolov4-coco', 'csp-darknet53-coco'};
            app.DropDown_4.ItemsData = {'1', '2'};
            app.DropDown_4.Value = '1';
            app.YOLOv3Tab = uitab(app.TabGroup);
            app.YOLOv3Tab.Title = 'YOLOv3';
            app.ButtonGroup = uibuttongroup(app.YOLOv3Tab);
            app.OptionTrainPanel_6 = uipanel(app.YOLOv3Tab);
            app.OptionTrainPanel_6.Title = 'Option & Train';
            app.OptionButton_6 = uibutton(app.OptionTrainPanel_6, 'push');
            app.OptionButton_6.Text = 'Option';
            app.YOLOv3TrainButton = uibutton(app.OptionTrainPanel_6, 'push');
            app.YOLOv3TrainButton.ButtonPushedFcn = createCallbackFcn(app, @YOLOv3TrainButtonPushed, true);
            app.YOLOv3TrainButton.Visible = 'off';
            app.YOLOv3TrainButton.Text = {'YOLOv3 '; 'Train'};
            app.YOLOv3TestButton = uibutton(app.OptionTrainPanel_6, 'push');
            app.YOLOv3TestButton.Visible = 'off';
            app.YOLOv3TestButton.Text = {'YOLOv3'; 'Test'};
            app.TrainLightLamp_5Label = uilabel(app.OptionTrainPanel_6);
            app.TrainLightLamp_5Label.Text = 'Train Light';
            app.TrainLightLamp_5 = uilamp(app.OptionTrainPanel_6);
            app.SplitDataAgumentDataPanel_4 = uipanel(app.YOLOv3Tab);
            app.SplitDataAgumentDataPanel_4.Title = 'Split Data & Agument Data';
            app.TrainEditField_4Label = uilabel(app.SplitDataAgumentDataPanel_4);
            app.TrainEditField_4Label.Text = 'Train(%)';
            app.TrainEditField_4 = uieditfield(app.SplitDataAgumentDataPanel_4, 'numeric');
            app.TrainEditField_4.Value = 85;
            app.AgumentDataCheckBox_4 = uicheckbox(app.SplitDataAgumentDataPanel_4);
            app.AgumentDataCheckBox_4.Text = 'Agument Data';
            app.AgumentDataCheckBox_4.Value = true;
            app.PreTrainNetworknumclassPanel = uipanel(app.YOLOv3Tab);
            app.PreTrainNetworknumclassPanel.Title = 'PreTrain Network & num class';
            app.ClassEditField_4Label = uilabel(app.PreTrainNetworknumclassPanel);
            app.ClassEditField_4Label.Text = 'Class';
            app.ClassEditField_4 = uieditfield(app.PreTrainNetworknumclassPanel, 'numeric');
            app.ClassEditField_4.Value = 1;
            app.DropDown_3 = uidropdown(app.PreTrainNetworknumclassPanel);
            app.DropDown_3.Items = {'Tiny-yolov3-coco', 'Darknet53-coco'};
            app.DropDown_3.ItemsData = {'1', '2'};
            app.DropDown_3.Value = '1';
            app.YOLOv2Tab = uitab(app.TabGroup);
            app.YOLOv2Tab.Title = 'YOLOv2';
            app.BaseNetworkButtonGroup_3 = uibuttongroup(app.YOLOv2Tab);
            app.BaseNetworkButtonGroup_3.Title = 'Base Network';
            app.DropDown_2 = uidropdown(app.BaseNetworkButtonGroup_3);
            app.DropDown_2.Items = {'Alexnet', 'VGG16', 'VGG19', 'Resnet18', 'Resnet50', 'Resnet101', 'Inceptionv3', 'Goolgenet', 'Inceptionresnetv2', 'squeezenet', 'mobilenetv2'};
            app.DropDown_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.DropDown_2.Value = '1';
            app.OptionTrainPanel_5 = uipanel(app.YOLOv2Tab);
            app.OptionTrainPanel_5.Title = 'Option & Train';
            app.OptionButton_5 = uibutton(app.OptionTrainPanel_5, 'push');
            app.OptionButton_5.Text = 'Option';
            app.YOLOv2TrainButton = uibutton(app.OptionTrainPanel_5, 'push');
            app.YOLOv2TrainButton.ButtonPushedFcn = createCallbackFcn(app, @YOLOv2TrainButtonPushed, true);
            app.YOLOv2TrainButton.Text = {'YOLOv2 '; 'Train'};
            app.YOLOv2TestButton = uibutton(app.OptionTrainPanel_5, 'push');
            app.YOLOv2TestButton.Visible = 'off';
            app.YOLOv2TestButton.Text = {'YOLOv2'; 'Test'};
            app.TrainLightLamp_4Label = uilabel(app.OptionTrainPanel_5);
            app.TrainLightLamp_4Label.Text = 'Train Light';
            app.TrainLightLamp_4 = uilamp(app.OptionTrainPanel_5);
            app.SplitDataAgumentDataPanel_3 = uipanel(app.YOLOv2Tab);
            app.SplitDataAgumentDataPanel_3.Title = 'Split Data & Agument Data';
            app.TrainEditField_3Label = uilabel(app.SplitDataAgumentDataPanel_3);
            app.TrainEditField_3Label.Text = 'Train(%)';
            app.TrainEditField_3 = uieditfield(app.SplitDataAgumentDataPanel_3, 'numeric');
            app.TrainEditField_3.Value = 85;
            app.AgumentDataCheckBox_3 = uicheckbox(app.SplitDataAgumentDataPanel_3);
            app.AgumentDataCheckBox_3.Text = 'Agument Data';
            app.AgumentDataCheckBox_3.Value = true;
            app.InputSizenumclassPanel_3 = uipanel(app.YOLOv2Tab);
            app.InputSizenumclassPanel_3.Title = 'Input Size & num class';
            app.HightEditField_6Label = uilabel(app.InputSizenumclassPanel_3);
            app.HightEditField_6Label.Text = 'Hight';
            app.HightEditField_6 = uieditfield(app.InputSizenumclassPanel_3, 'numeric');
            app.HightEditField_6.Value = 224;
            app.WeightEditField_6Label = uilabel(app.InputSizenumclassPanel_3);
            app.WeightEditField_6Label.Text = 'Weight';
            app.WeightEditField_6 = uieditfield(app.InputSizenumclassPanel_3, 'numeric');
            app.WeightEditField_6.Value = 224;
            app.ClassEditField_3Label = uilabel(app.InputSizenumclassPanel_3);
            app.ClassEditField_3Label.Text = 'Class';
            app.ClassEditField_3 = uieditfield(app.InputSizenumclassPanel_3, 'numeric');
            app.ClassEditField_3.Value = 1;
            app.AnchorBoxEditFieldLabel = uilabel(app.InputSizenumclassPanel_3);
            app.AnchorBoxEditFieldLabel.Text = 'Anchor Box';
            app.AnchorBoxEditField = uieditfield(app.InputSizenumclassPanel_3, 'numeric');
            app.AnchorBoxEditField.Value = 6;
            app.SSDTab = uitab(app.TabGroup);
            app.SSDTab.Title = 'SSD';
            app.BaseNetworkButtonGroup = uibuttongroup(app.SSDTab);
            app.BaseNetworkButtonGroup.Title = 'Base Network';
            app.VGG16Button = uiradiobutton(app.BaseNetworkButtonGroup);
            app.VGG16Button.Text = 'VGG16';
            app.Resnet50Button = uiradiobutton(app.BaseNetworkButtonGroup);
            app.Resnet50Button.Text = 'Resnet50';
            app.Resnet50Button.Value = true;
            app.Resnet101Button = uiradiobutton(app.BaseNetworkButtonGroup);
            app.Resnet101Button.Text = 'Resnet101';
            app.OptionTrainPanel_2 = uipanel(app.SSDTab);
            app.OptionTrainPanel_2.Title = 'Option & Train';
            app.OptionButton = uibutton(app.OptionTrainPanel_2, 'push');
            app.OptionButton.Text = 'Option';
            app.SSDTrainButton = uibutton(app.OptionTrainPanel_2, 'push');
            app.SSDTrainButton.ButtonPushedFcn = createCallbackFcn(app, @SSDTrainButtonPushed, true);
            app.SSDTrainButton.Text = 'SSDTrain';
            app.TestButton = uibutton(app.OptionTrainPanel_2, 'push');
            app.TestButton.Visible = 'off';
            app.TestButton.Text = 'Test';
            app.TrainLightLamp_3Label = uilabel(app.OptionTrainPanel_2);
            app.TrainLightLamp_3Label.Text = 'Train Light';
            app.TrainLightLamp_3 = uilamp(app.OptionTrainPanel_2);
            app.SplitDataAgumentDataPanel = uipanel(app.SSDTab);
            app.SplitDataAgumentDataPanel.Title = 'Split Data & Agument Data';
            app.TrainEditFieldLabel = uilabel(app.SplitDataAgumentDataPanel);
            app.TrainEditFieldLabel.Text = 'Train(%)';
            app.TrainEditField = uieditfield(app.SplitDataAgumentDataPanel, 'numeric');
            app.TrainEditField.ValueChangedFcn = createCallbackFcn(app, @TrainEditFieldValueChanged, true);
            app.TrainEditField.Value = 85;
            app.AgumentDataCheckBox = uicheckbox(app.SplitDataAgumentDataPanel);
            app.AgumentDataCheckBox.Text = 'Agument Data';
            app.AgumentDataCheckBox.Value = true;
            app.InputSizenumclassPanel = uipanel(app.SSDTab);
            app.InputSizenumclassPanel.Title = 'Input Size & num class';
            app.HightEditField_4Label = uilabel(app.InputSizenumclassPanel);
            app.HightEditField_4Label.Text = 'Hight';
            app.HightEditField_4 = uieditfield(app.InputSizenumclassPanel, 'numeric');
            app.HightEditField_4.Value = 300;
            app.WeightEditField_4Label = uilabel(app.InputSizenumclassPanel);
            app.WeightEditField_4Label.Text = 'Weight';
            app.WeightEditField_4 = uieditfield(app.InputSizenumclassPanel, 'numeric');
            app.WeightEditField_4.Value = 300;
            app.ClassEditFieldLabel = uilabel(app.InputSizenumclassPanel);
            app.ClassEditFieldLabel.Text = 'Class';
            app.ClassEditField = uieditfield(app.InputSizenumclassPanel, 'numeric');
            app.ClassEditField.Value = 1;
            app.FasterRCNNTab = uitab(app.TabGroup);
            app.FasterRCNNTab.Title = 'Faster RCNN';
            app.BaseNetworkButtonGroup_2 = uibuttongroup(app.FasterRCNNTab);
            app.BaseNetworkButtonGroup_2.Title = 'Base Network';
            app.DropDown = uidropdown(app.BaseNetworkButtonGroup_2);
            app.DropDown.Items = {'Alexnet', 'VGG16', 'VGG19', 'Resnet18', 'Resnet50', 'Resnet101', 'Inceptionv3', 'Goolgenet', 'Inceptionresnetv2', 'squeezenet', 'mobilenetv2'};
            app.DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.DropDown.Value = '1';
            app.OptionTrainPanel_4 = uipanel(app.FasterRCNNTab);
            app.OptionTrainPanel_4.Title = 'Option & Train';
            app.OptionButton_4 = uibutton(app.OptionTrainPanel_4, 'push');
            app.OptionButton_4.ButtonPushedFcn = createCallbackFcn(app, @OptionButton_4Pushed, true);
            app.OptionButton_4.Text = 'Option';
            app.FasterRCNNTrainButton = uibutton(app.OptionTrainPanel_4, 'push');
            app.FasterRCNNTrainButton.ButtonPushedFcn = createCallbackFcn(app, @FasterRCNNTrainButtonPushed, true);
            app.FasterRCNNTrainButton.Text = {'Faster RCNN '; 'Train'};
            app.FasterRCNNTestButton = uibutton(app.OptionTrainPanel_4, 'push');
            app.FasterRCNNTestButton.Visible = 'off';
            app.FasterRCNNTestButton.Text = {'Faster RCNN'; 'Test'};
            app.TrainLightLamp_2Label = uilabel(app.OptionTrainPanel_4);
            app.TrainLightLamp_2Label.Text = 'Train Light';
            app.TrainLightLamp_2 = uilamp(app.OptionTrainPanel_4);
            app.SplitDataAgumentDataPanel_2 = uipanel(app.FasterRCNNTab);
            app.SplitDataAgumentDataPanel_2.Title = 'Split Data & Agument Data';
            app.TrainEditField_2Label = uilabel(app.SplitDataAgumentDataPanel_2);
            app.TrainEditField_2Label.Text = 'Train(%)';
            app.TrainEditField_2 = uieditfield(app.SplitDataAgumentDataPanel_2, 'numeric');
            app.TrainEditField_2.Value = 85;
            app.AgumentDataCheckBox_2 = uicheckbox(app.SplitDataAgumentDataPanel_2);
            app.AgumentDataCheckBox_2.Text = 'Agument Data';
            app.AgumentDataCheckBox_2.Value = true;
            app.InputSizenumclassPanel_2 = uipanel(app.FasterRCNNTab);
            app.InputSizenumclassPanel_2.Title = 'Input Size & num class';
            app.HightEditField_5Label = uilabel(app.InputSizenumclassPanel_2);
            app.HightEditField_5Label.Text = 'Hight';
            app.HightEditField_5 = uieditfield(app.InputSizenumclassPanel_2, 'numeric');
            app.HightEditField_5.Value = 224;
            app.WeightEditField_5Label = uilabel(app.InputSizenumclassPanel_2);
            app.WeightEditField_5Label.Text = 'Weight';
            app.WeightEditField_5 = uieditfield(app.InputSizenumclassPanel_2, 'numeric');
            app.WeightEditField_5.Value = 224;
            app.ClassEditField_2Label = uilabel(app.InputSizenumclassPanel_2);
            app.ClassEditField_2Label.Text = 'Class';
            app.ClassEditField_2 = uieditfield(app.InputSizenumclassPanel_2, 'numeric');
            app.ClassEditField_2.Value = 1;
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.MATLABTab = uitab(app.TabGroup2);
            app.MATLABTab.Title = 'MATLAB';
            app.LoadTrainDataPanel = uipanel(app.MATLABTab);
            app.LoadTrainDataPanel.Title = 'Load Train Data';
            app.TableDataButton = uibutton(app.LoadTrainDataPanel, 'push');
            app.TableDataButton.ButtonPushedFcn = createCallbackFcn(app, @TableDataButtonPushed, true);
            app.TableDataButton.Text = 'Table Data';
            app.LoadTestDataPanel = uipanel(app.MATLABTab);
            app.LoadTestDataPanel.Title = 'Load Test Data';
            app.TableDataButton_2 = uibutton(app.LoadTestDataPanel, 'push');
            app.TableDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @TableDataButton_2Pushed, true);
            app.TableDataButton_2.Text = 'Table Data';
            app.JsonTab = uitab(app.TabGroup2);
            app.JsonTab.Title = 'Json';
            app.LoadTrainDataPanel_2 = uipanel(app.JsonTab);
            app.LoadTrainDataPanel_2.Title = 'Load Train Data';
            app.JsonFileButton = uibutton(app.LoadTrainDataPanel_2, 'push');
            app.JsonFileButton.ButtonPushedFcn = createCallbackFcn(app, @JsonFileButtonPushed, true);
            app.JsonFileButton.Visible = 'off';
            app.JsonFileButton.Text = 'Json File';
            app.LoadTestDataPanel_2 = uipanel(app.JsonTab);
            app.LoadTestDataPanel_2.Title = 'Load Test Data';
            app.JsonFileButton_2 = uibutton(app.LoadTestDataPanel_2, 'push');
            app.JsonFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @JsonFileButton_2Pushed, true);
            app.JsonFileButton_2.Visible = 'off';
            app.JsonFileButton_2.Text = 'Json File';
            app.XMLTab = uitab(app.TabGroup2);
            app.XMLTab.Title = 'XML';
            app.LoadTrainDataPanel_3 = uipanel(app.XMLTab);
            app.LoadTrainDataPanel_3.Title = 'Load Train Data';
            app.XMLFileButton = uibutton(app.LoadTrainDataPanel_3, 'push');
            app.XMLFileButton.ButtonPushedFcn = createCallbackFcn(app, @XMLFileButtonPushed, true);
            app.XMLFileButton.Visible = 'off';
            app.XMLFileButton.Text = 'XML File';
            app.ImageFileButton = uibutton(app.LoadTrainDataPanel_3, 'push');
            app.ImageFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImageFileButtonPushed, true);
            app.ImageFileButton.Visible = 'off';
            app.ImageFileButton.Text = 'Image File';
            app.LoadTestDataPanel_3 = uipanel(app.XMLTab);
            app.LoadTestDataPanel_3.Title = 'Load Test Data';
            app.XMLFileButton_2 = uibutton(app.LoadTestDataPanel_3, 'push');
            app.XMLFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @XMLFileButton_2Pushed, true);
            app.XMLFileButton_2.Visible = 'off';
            app.XMLFileButton_2.Text = 'XML File';
            app.ImageFileButton_2 = uibutton(app.LoadTestDataPanel_3, 'push');
            app.ImageFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImageFileButton_2Pushed, true);
            app.ImageFileButton_2.Visible = 'off';
            app.ImageFileButton_2.Text = 'Image File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ObjD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end