classdef OptionsList < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CheckpointPanel               matlab.ui.container.Panel
        CheckpointPathEditField       matlab.ui.control.EditField
        CheckpointPathEditFieldLabel  matlab.ui.control.Label
        DropDown_11                   matlab.ui.control.DropDown
        CheckpointFrequencyUnitLabel  matlab.ui.control.Label
        EditField_16                  matlab.ui.control.NumericEditField
        CheckpointFrequencyLabel      matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        UpdateButton                  matlab.ui.control.Button
        HardwareOptionsPanel          matlab.ui.container.Panel
        DropDown_8                    matlab.ui.control.DropDown
        ExecutionEnvironmentLabel     matlab.ui.control.Label
        GradientClippingPanel         matlab.ui.container.Panel
        DropDown_6                    matlab.ui.control.DropDown
        GradientThresholdMethodLabel  matlab.ui.control.Label
        EditField_14                  matlab.ui.control.NumericEditField
        GradientThresholdLabel        matlab.ui.control.Label
        SolverOptionsPanel            matlab.ui.container.Panel
        DropDown_10                   matlab.ui.control.DropDown
        DropDown_9                    matlab.ui.control.DropDown
        BatchNormalizationStatisticsLabel  matlab.ui.control.Label
        ResetInputNormalizationLabel  matlab.ui.control.Label
        EditField_13                  matlab.ui.control.NumericEditField
        EpsilonLabel                  matlab.ui.control.Label
        EditField_12                  matlab.ui.control.NumericEditField
        SquaredGradientDecayFactorLabel  matlab.ui.control.Label
        GradientDecayFactorLabel      matlab.ui.control.Label
        EditField_11                  matlab.ui.control.NumericEditField
        EditField_10                  matlab.ui.control.NumericEditField
        MomentumLabel                 matlab.ui.control.Label
        EditField_9                   matlab.ui.control.NumericEditField
        L2RegularizationLabel         matlab.ui.control.Label
        EditField_8                   matlab.ui.control.NumericEditField
        LearnRateDropFactorLabel      matlab.ui.control.Label
        EditField_7                   matlab.ui.control.NumericEditField
        LearnRateDropPeriodLabel      matlab.ui.control.Label
        DropDown_5                    matlab.ui.control.DropDown
        InitialLearnScheduleLabel     matlab.ui.control.Label
        EditField_6                   matlab.ui.control.NumericEditField
        InitialLearnRateLabel         matlab.ui.control.Label
        ValidationPanel               matlab.ui.container.Panel
        EditField_5                   matlab.ui.control.NumericEditField
        ValidationPatienceLabel       matlab.ui.control.Label
        EditField_4                   matlab.ui.control.NumericEditField
        ValidationFrequencyLabel      matlab.ui.control.Label
        MiniBatchOptionsPanel         matlab.ui.container.Panel
        DropDown_4                    matlab.ui.control.DropDown
        ShuffleLabel                  matlab.ui.control.Label
        EditField_3                   matlab.ui.control.NumericEditField
        MiniBatchSizeLabel            matlab.ui.control.Label
        EditField_2                   matlab.ui.control.NumericEditField
        MaxEpochsLabel                matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        SolverNameLabel               matlab.ui.control.Label
        TrainingOptionsLabel          matlab.ui.control.Label
    end


    properties (Access = private)
        callMainApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.callMainApp = mainApp;

            %Solvername
            app.DropDown.Value = mainApp.op_Solvername;

            %Mini_batch Options
            %1
            app.EditField_2.Value = mainApp.op_Max_Epochs;
            %2
            app.EditField_3.Value = mainApp.op_Mini_Batch_Size;
            %3
            app.DropDown_4.Value = mainApp.op_Shuffle;

            %Validation
            app.EditField_4.Value = mainApp.op_ValidationFrequency;
            app.EditField_5.Value = mainApp.op_ValidationPatience;

            %SolverOption
            app.EditField_6.Value = mainApp.op_InitialLearnRate;
            app.DropDown_5.Value = mainApp.op_LearnRateSchedule;
            app.EditField_7.Value = mainApp.op_LearnRateDropPeriod;
            app.EditField_8.Value = mainApp.op_LearnRateDropFactor;
            app.EditField_9.Value = mainApp.op_L2_Regularization;
            app.EditField_10.Value = mainApp.op_Momentum;
            app.EditField_11.Value = mainApp.op_GradientDecayFactor;
            app.EditField_12.Value = mainApp.op_SquaredGradientDecayFactor;
            app.EditField_13.Value = mainApp.op_Epsilon;
            app.DropDown_9.Value   = mainApp.op_ResetInputNormalization;
            app.DropDown_10.Value   = mainApp.op_BatchNormalizationStatistics;
            
            %CheckPoint
            app.EditField_16.Value = mainApp.op_CheckpointFrequencyLabel;
            app.DropDown_11.Value =  mainApp.op_CheckpointFrequencyUnitLabel;
            app.CheckpointPathEditField.Value  = mainApp.op_CheckpointPathEditField;

            %Gradient Cliping
            app.EditField_14.Value = mainApp.op_Gradient_Threshold;
            app.DropDown_6.Value = mainApp.op_Gradient_Threshold_Method;

            %Hardware Option
            app.DropDown_8.Value = mainApp.op_Execution_Environment;

        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
        %Solvername
            app.callMainApp.op_Solvername = app.DropDown.Value;
            
            %Mini_batch Options
            %1
            app.callMainApp.op_Max_Epochs = app.EditField_2.Value;
            %2
            app.callMainApp.op_Mini_Batch_Size = app.EditField_3.Value;
            %3
            app.callMainApp.op_Shuffle = app.DropDown_4.Value;
            
            %Validation
            app.callMainApp.op_ValidationFrequency = app.EditField_4.Value;
            app.callMainApp.op_ValidationPatience = app.EditField_5.Value;
            
            %SolverOption
            app.callMainApp.op_InitialLearnRate = app.EditField_6.Value;
            app.callMainApp.op_LearnRateSchedule = app.DropDown_5.Value;
            app.callMainApp.op_LearnRateDropPeriod = app.EditField_7.Value;
            app.callMainApp.op_LearnRateDropFactor = app.EditField_8.Value;
            app.callMainApp.op_L2_Regularization = app.EditField_9.Value;
            app.callMainApp.op_Momentum = app.EditField_10.Value;
            app.callMainApp.op_GradientDecayFactor = app.EditField_11.Value;
            app.callMainApp.op_SquaredGradientDecayFactor = app.EditField_12.Value;
            app.callMainApp.op_Epsilon = app.EditField_13.Value;
            app.callMainApp.op_ResetInputNormalization = app.DropDown_9.Value;
            app.callMainApp.op_BatchNormalizationStatistics = app.DropDown_10.Value;

            %Gradient Cliping
            app.callMainApp.op_Gradient_Threshold = app.EditField_14.Value;
            app.callMainApp.op_Gradient_Threshold_Method = app.DropDown_6.Value;
            
            %CheckPoint
            app.callMainApp.op_CheckpointFrequencyLabel = app.EditField_16.Value;
            app.callMainApp.op_CheckpointFrequencyUnitLabel = app.DropDown_11.Value;
            app.callMainApp.op_CheckpointPathEditField = app.CheckpointPathEditField;

            %Hardware Option
            app.callMainApp.op_Execution_Environment=app.DropDown_8.Value;
            
            app.UIFigureCloseRequest();


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TrainingOptionsLabel = uilabel(app.UIFigure);
            app.TrainingOptionsLabel.Text = 'Training Options';
            app.SolverNameLabel = uilabel(app.UIFigure);
            app.SolverNameLabel.Text = 'Solver Name';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'sgdm', 'rmsprop', 'adam'};
            app.DropDown.ItemsData = [1 2 3];
            app.DropDown.Value = 1;
            app.MiniBatchOptionsPanel = uipanel(app.UIFigure);
            app.MiniBatchOptionsPanel.Title = 'Mini-Batch Options';
            app.MaxEpochsLabel = uilabel(app.MiniBatchOptionsPanel);
            app.MaxEpochsLabel.Text = 'Max Epochs';
            app.EditField_2 = uieditfield(app.MiniBatchOptionsPanel, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.ValueDisplayFormat = '%d';
            app.EditField_2.Value = 20;
            app.MiniBatchSizeLabel = uilabel(app.MiniBatchOptionsPanel);
            app.MiniBatchSizeLabel.Text = 'Mini Batch Size';
            app.EditField_3 = uieditfield(app.MiniBatchOptionsPanel, 'numeric');
            app.EditField_3.Limits = [0 Inf];
            app.EditField_3.ValueDisplayFormat = '%d';
            app.EditField_3.Value = 32;
            app.ShuffleLabel = uilabel(app.MiniBatchOptionsPanel);
            app.ShuffleLabel.Text = 'Shuffle';
            app.DropDown_4 = uidropdown(app.MiniBatchOptionsPanel);
            app.DropDown_4.Items = {'once', 'never', 'every-epoch'};
            app.DropDown_4.ItemsData = [1 2 3];
            app.DropDown_4.Value = 3;
            app.ValidationPanel = uipanel(app.UIFigure);
            app.ValidationPanel.Title = 'Validation';
            app.ValidationFrequencyLabel = uilabel(app.ValidationPanel);
            app.ValidationFrequencyLabel.Text = 'ValidationFrequency';
            app.EditField_4 = uieditfield(app.ValidationPanel, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.ValueDisplayFormat = '%d';
            app.EditField_4.Value = 50;
            app.ValidationPatienceLabel = uilabel(app.ValidationPanel);
            app.ValidationPatienceLabel.Text = 'Validation Patience';
            app.EditField_5 = uieditfield(app.ValidationPanel, 'numeric');
            app.EditField_5.Limits = [0 Inf];
            app.EditField_5.Value = Inf;
            app.SolverOptionsPanel = uipanel(app.UIFigure);
            app.SolverOptionsPanel.Title = 'Solver Options';
            app.InitialLearnRateLabel = uilabel(app.SolverOptionsPanel);
            app.InitialLearnRateLabel.Text = 'Initial Learn Rate';
            app.EditField_6 = uieditfield(app.SolverOptionsPanel, 'numeric');
            app.EditField_6.Limits = [0 Inf];
            app.EditField_6.Value = 0.01;
            app.InitialLearnScheduleLabel = uilabel(app.SolverOptionsPanel);
            app.InitialLearnScheduleLabel.Text = 'Initial Learn Schedule';
            app.DropDown_5 = uidropdown(app.SolverOptionsPanel);
            app.DropDown_5.Items = {'none', 'piecewise'};
            app.DropDown_5.ItemsData = [1 2];
            app.DropDown_5.Value = 1;
            app.LearnRateDropPeriodLabel = uilabel(app.SolverOptionsPanel);
            app.LearnRateDropPeriodLabel.Text = 'Learn Rate Drop Period';
            app.EditField_7 = uieditfield(app.SolverOptionsPanel, 'numeric');
            app.EditField_7.Limits = [0 Inf];
            app.EditField_7.Value = 10;
            app.LearnRateDropFactorLabel = uilabel(app.SolverOptionsPanel);
            app.LearnRateDropFactorLabel.Text = 'Learn Rate Drop Factor';
            app.EditField_8 = uieditfield(app.SolverOptionsPanel, 'numeric');
            app.EditField_8.Limits = [0 1];
            app.EditField_8.Value = 0.1;
            app.L2RegularizationLabel = uilabel(app.SolverOptionsPanel);
            app.L2RegularizationLabel.Text = 'L2Regularization';
            app.EditField_9 = uieditfield(app.SolverOptionsPanel, 'numeric');
            app.EditField_9.Limits = [0 Inf];
            app.EditField_9.Value = 0.0001;
            app.MomentumLabel = uilabel(app.SolverOptionsPanel);
            app.MomentumLabel.Text = 'Momentum';
            app.EditField_10 = uieditfield(app.SolverOptionsPanel, 'numeric');
            app.EditField_10.Limits = [0 1];
            app.EditField_10.Value = 0.9;
            app.EditField_11 = uieditfield(app.SolverOptionsPanel, 'numeric');
            app.EditField_11.Limits = [0 1];
            app.EditField_11.Enable = 'off';
            app.EditField_11.Value = 0.9;
            app.GradientDecayFactorLabel = uilabel(app.SolverOptionsPanel);
            app.GradientDecayFactorLabel.Text = 'Gradient Decay Factor';
            app.SquaredGradientDecayFactorLabel = uilabel(app.SolverOptionsPanel);
            app.SquaredGradientDecayFactorLabel.Text = 'Squared Gradient Decay Factor';
            app.EditField_12 = uieditfield(app.SolverOptionsPanel, 'numeric');
            app.EditField_12.Limits = [0 1];
            app.EditField_12.Enable = 'off';
            app.EditField_12.Value = 0.999;
            app.EpsilonLabel = uilabel(app.SolverOptionsPanel);
            app.EpsilonLabel.Text = 'Epsilon';
            app.EditField_13 = uieditfield(app.SolverOptionsPanel, 'numeric');
            app.EditField_13.Limits = [0 Inf];
            app.EditField_13.Enable = 'off';
            app.EditField_13.Value = 1e-08;
            app.ResetInputNormalizationLabel = uilabel(app.SolverOptionsPanel);
            app.ResetInputNormalizationLabel.Text = 'ResetInputNormalization';
            app.BatchNormalizationStatisticsLabel = uilabel(app.SolverOptionsPanel);
            app.BatchNormalizationStatisticsLabel.Text = 'BatchNormalizationStatistics';
            app.DropDown_9 = uidropdown(app.SolverOptionsPanel);
            app.DropDown_9.Items = {'True', 'False'};
            app.DropDown_9.ItemsData = [1 2];
            app.DropDown_9.Value = 1;
            app.DropDown_10 = uidropdown(app.SolverOptionsPanel);
            app.DropDown_10.Items = {'Population', 'moving'};
            app.DropDown_10.ItemsData = [1 2];
            app.DropDown_10.Value = 1;
            app.GradientClippingPanel = uipanel(app.UIFigure);
            app.GradientClippingPanel.Title = 'Gradient Clipping';
            app.GradientThresholdLabel = uilabel(app.GradientClippingPanel);
            app.GradientThresholdLabel.Text = 'Gradient Threshold';
            app.EditField_14 = uieditfield(app.GradientClippingPanel, 'numeric');
            app.EditField_14.Limits = [0 Inf];
            app.EditField_14.Value = Inf;
            app.GradientThresholdMethodLabel = uilabel(app.GradientClippingPanel);
            app.GradientThresholdMethodLabel.Text = 'Gradient Threshold Method';
            app.DropDown_6 = uidropdown(app.GradientClippingPanel);
            app.DropDown_6.Items = {'l2norm', 'global-l2norm', 'absolute-value'};
            app.DropDown_6.ItemsData = [1 2 3];
            app.DropDown_6.Value = 1;
            app.HardwareOptionsPanel = uipanel(app.UIFigure);
            app.HardwareOptionsPanel.Title = 'Hardware Options';
            app.ExecutionEnvironmentLabel = uilabel(app.HardwareOptionsPanel);
            app.ExecutionEnvironmentLabel.Text = 'Execution Environment';
            app.DropDown_8 = uidropdown(app.HardwareOptionsPanel);
            app.DropDown_8.Items = {'cpu', 'gpu', 'multi-gpu', 'parallel'};
            app.DropDown_8.ItemsData = [1 2 3 4];
            app.DropDown_8.Value = 2;
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.CheckpointPanel = uipanel(app.UIFigure);
            app.CheckpointPanel.Title = 'Checkpoint';
            app.Label = uilabel(app.CheckpointPanel);
            app.Label.Text = '';
            app.CheckpointFrequencyLabel = uilabel(app.CheckpointPanel);
            app.CheckpointFrequencyLabel.Text = 'CheckpointFrequency';
            app.EditField_16 = uieditfield(app.CheckpointPanel, 'numeric');
            app.EditField_16.Limits = [0 1];
            app.EditField_16.Value = 1;
            app.CheckpointFrequencyUnitLabel = uilabel(app.CheckpointPanel);
            app.CheckpointFrequencyUnitLabel.Text = 'CheckpointFrequencyUnit';
            app.DropDown_11 = uidropdown(app.CheckpointPanel);
            app.DropDown_11.Items = {'epoch', 'iteration'};
            app.DropDown_11.ItemsData = [1 2];
            app.DropDown_11.Value = 1;
            app.CheckpointPathEditFieldLabel = uilabel(app.CheckpointPanel);
            app.CheckpointPathEditFieldLabel.Text = 'CheckpointPath';
            app.CheckpointPathEditField = uieditfield(app.CheckpointPanel, 'text');
            app.CheckpointPathEditField.Value = 'tempdir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptionsList(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end